classdef LaminAnalyzerClean < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        IMPORTButton             matlab.ui.control.Button
        DAPIChEditField          matlab.ui.control.NumericEditField
        Marker1ChEditField       matlab.ui.control.NumericEditField
        Marker2ChEditField       matlab.ui.control.NumericEditField
        PROCEEDButton            matlab.ui.control.Button
        ChannelSpinner           matlab.ui.control.Spinner
        zsliceSpinner            matlab.ui.control.Spinner
        IgnorefloorholesLabel    matlab.ui.control.Label
        CheckBox_IgnoreHoles     matlab.ui.control.CheckBox
        InsideOutRaysLabel       matlab.ui.control.Label
        CheckBox_InsideOut       matlab.ui.control.CheckBox
        OutsideInRaysLabel       matlab.ui.control.Label
        CheckBox_OutsideIn       matlab.ui.control.CheckBox
        DAPIChEditFieldLabel     matlab.ui.control.Label
        ComputeHolesButton       matlab.ui.control.Button
        Switch                   matlab.ui.control.Switch
        MaxLabel                 matlab.ui.control.Label
        MinLabel                 matlab.ui.control.Label
        SizeFilterSpinner        matlab.ui.control.Spinner
        UITable                  matlab.ui.control.Table
        SAVEButton_NUC           matlab.ui.control.Button
        CheckBox_3               matlab.ui.control.CheckBox
        CheckBox_2               matlab.ui.control.CheckBox
        CheckBox_1               matlab.ui.control.CheckBox
        GIFCheckBox              matlab.ui.control.CheckBox
        SingleFrameCheckBox      matlab.ui.control.CheckBox
        SNAPButton               matlab.ui.control.Button
        EXPORTButton             matlab.ui.control.Button
        SizeFilterSpinnerLabel   matlab.ui.control.Label
        zsliceSpinnerLabel       matlab.ui.control.Label
        ChannelSpinnerLabel      matlab.ui.control.Label
        Marker2ChEditFieldLabel  matlab.ui.control.Label
        Marker1ChEditFieldLabel  matlab.ui.control.Label
        OverlayPanel             matlab.ui.container.Panel
        LaminHolesPanel          matlab.ui.container.Panel
        LaminMeshPanel           matlab.ui.container.Panel
        UIAxes_zSlices           matlab.ui.control.UIAxes
        UIAxes_Nuclei            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        filename % name of the dataset file
        Stack % 4D image stack(XYZC)
        LaminStack % Lamin channel stack
        Thresh % Lamin threshold for binarization
        LaminStackCrop % current nucleus Lamin Stack
        SLamin % Lamin BW 2D proj properties
        DAPIMP % DAPI Channel max Proj
        himLam % Handle for image max Proj
        wltext % text for wavelength in proj images
        sinfo % metadata of image set
        HOLESW % BW computed lamin holes
        fHOLESW % size-filtered HOLESW
        MESHW % BW computed Lamin mesh
        MESHDW % BW DAPI
        PerimW % BW perim of DAPI signal
        LaminHULLW % BWconvhull of Lamin signal
        lvolholes % volview holes
        lvolmesh % volview mesh
        lvolcombo % volview mesh and holes
        lvolholeonlyselect % volview of selected holes in Holes only panel
        lvolholeselect % volview of selected holes
        curLamin % current nuclear stack Lamin channel
        origLamin % origin Lamin
        origDAPI % origin DAPI
        origLamin2 % origin Marker2
        DAPIStack % 3D DAPI grayscale stack
        Lamin2Stack % 3D actin grayscale stack
        qimax % 99% quantile for actin stack
        hima % image handle for actin
        Lamin2Crop % Cropped actin to nucleus
        DAPICrop % Cropped DAPI to nucleus
        CropStack % 4D stack of current cropped nucleus
        viewer % combo viewer 3d
        holeviewer % hole viewer 3d
        lvolcombholes % volview holes in combo panel
        lineprofile % original actin line profile
        smoothprofile % smoothed actin profile
        xprofile % x-axis intensity profile
        pplot %handle of smoothed plot
        pks % peaks intensities
        locs % peaks locations
        widths % peaks widths
        ppeak % handle of plotted peaks
        exportdata % saved data ready to be exported
        Ssave % regionproperties of the current nucleus'holes, sued to map the holes after selection on table
        scount % indices of saved traces
        sncount % indices of saved nuclei holes
        snncount % indices of saved nuclei meshes
        k % Nuclear ID
        brf % boundary connectivity
        Prb % boundary point coordinates
        curvature % curvature
        sax % handle for curvature axes
        hs % handle for surf plot

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IMPORTButton
        function IMPORTButtonPushed(app, event)
            
            app.exportdata=[];
            app.UIAxes_Nuclei.Title.Color='w';
            app.UIAxes_zSlices.Title.Color='w';
            
            app.DAPIChEditField.Editable="on";
            app.DAPIChEditField.Value=1;
            %app.DAPIChEditField.Enable="on";
            app.Marker1ChEditField.Editable="on";
            app.Marker1ChEditField.Value=2;
            %app.Marker1ChEditField.Enable="on";
            app.Marker2ChEditField.Editable="on";
            app.Marker2ChEditField.Value=3;
            %app.Marker2ChEditField.Enable="on";
            app.ChannelSpinner.Editable="on";
            app.ChannelSpinner.Enable="on";
            app.PROCEEDButton.Enable="on";
            app.Switch.Enable='off';
            app.ChannelSpinner.Value=1;
            app.zsliceSpinner.Value=1;
            

            app.MinLabel.Text="";
            app.MaxLabel.Text="";
            app.SizeFilterSpinner.Value=0;
            app.SizeFilterSpinner.Enable='off';


            cla(app.UIAxes_Nuclei);
            cla(app.UIAxes_zSlices);
            app.UITable.Visible='off';
            

            if ~isempty(app.LaminMeshPanel.Children)
                delete(app.lvolmesh.Parent)
            end
            if ~isempty(app.LaminHolesPanel.Children)
                delete(app.lvolholes.Parent)
            end
            if ~isempty(app.OverlayPanel.Children)
                delete(app.lvolcombo.Parent)
            end

            if ~isempty(app.sax)
                delete(app.sax);
            end


            app.scount=1;
            app.sncount=1;
            app.snncount=1;


            app.UIFigure.Visible="off";
            commandwindow
            [file, path]=uigetfile('*.*');
            if file==0
                app.UIFigure.Visible='on';
                return
            end
            app.exportdata.fname=file(1:end-4);
            app.filename=fullfile(path,file);
            app.UIFigure.Visible='on';
            oldpointer=app.UIFigure.Pointer;
            try
            [reader, ~, app.sinfo]=bfGetInfo(app.filename);
            catch
                uialert(app.UIFigure,'Unable to read file','Cancelling...');
                return
            end
            if numel(app.sinfo)>1
                 uialert(app.UIFigure,'Can only take one stack at a time',...
                    'Cancelling');
                 return
            end

            if app.sinfo.Z==1
                uialert(app.UIFigure,'dataset is not a 3D stack',...
                    'Cancelling...');
                return
            end
            if app.sinfo.C==1
                uialert(app.UIFigure,'Not enough channels (at least 2 are needed, DNA and NE marker)',...
                    'Cancelling');
                return
            end
            if strcmp(app.sinfo.pixUnit,'Pixel')
                uialert(app.UIFigure,'metadata not found: size units in Pixels!',...
                    'WARNING!');
            end


            if app.sinfo.C==2
                app.Marker2ChEditField.Value=0;
            end

            
            app.UIFigure.Pointer="watch";drawnow;
            app.Stack=zeros(app.sinfo.X,app.sinfo.Y,app.sinfo.Z,app.sinfo.C,app.sinfo.PixelType);
            for ch=1:app.sinfo.C
                for zslice=1:app.sinfo.Z
                    iplane=reader.getIndex(zslice-1,ch-1,0)+1;
                    app.Stack(:,:,zslice,ch)=bfGetPlane(reader,iplane);
                end
            end
            app.ChannelSpinner.Limits=[1 app.sinfo.C];
            app.zsliceSpinner.Limits=[1 app.sinfo.Z];
            app.DAPIChEditField.Limits=[1 app.sinfo.C];
            app.Marker1ChEditField.Limits=[1 app.sinfo.C];
            app.Marker2ChEditField.Limits=[0 Inf];


            StartIM=max(app.Stack(:,:,:,1),[],3);
            hold(app.UIAxes_Nuclei,'on');
            app.himLam=imshow(StartIM,[],'Parent',app.UIAxes_Nuclei);
            if any(app.sinfo.wavelength)
            app.wltext=text(app.UIAxes_Nuclei,30, 30,...
                ['Ex:' int2str(app.sinfo.wavelength(app.ChannelSpinner.Value)) ' nm'],'Color','w');
            else
             app.wltext=text(app.UIAxes_Nuclei,30, 30,...
                'Ex: N.D','Color','w');   
            end
            app.UIFigure.Pointer=oldpointer;drawnow;
            app.DAPIChEditField.Enable="on";
            app.Marker1ChEditField.Enable="on";
            app.Marker2ChEditField.Enable="on";


        end

        % Value changed function: DAPIChEditField
        function DAPIChEditFieldValueChanged(app, event)
            dapivalue = app.DAPIChEditField.Value;
            if dapivalue>app.sinfo.C
                dapivalue=app.sinfo.C;
            elseif dapivalue==0
                dapivalue=1;
            end
            if dapivalue==app.Marker1ChEditField.Value
                if dapivalue+1<=app.sinfo.C
                    app.Marker1ChEditField.Value=dapivalue+1;
                else
                    app.Marker1ChEditField.Value=dapivalue-1;
                end
            end
            if dapivalue==app.Marker2ChEditField.Value
                chchoice2=find(~ismember(1:app.sinfo.C,...
                    [dapivalue app.Marker1ChEditField.Value]));
                app.Marker2ChEditField.Value=chchoice2(end);
            end
            app.DAPIChEditField.Value=dapivalue;
            
        end

        % Value changed function: Marker1ChEditField
        function Marker1ChEditFieldValueChanged(app, event)
            marker1value = app.Marker1ChEditField.Value;
            if marker1value>app.sinfo.C || marker1value==0 ||...
                    marker1value==app.DAPIChEditField.Value
                chchoice=find(1:app.sinfo.C~=app.DAPIChEditField.Value);
                marker1value=chchoice(end);
            end
            if marker1value==app.Marker2ChEditField.Value
                chchoice2=find(~ismember(1:app.sinfo.C,...
                    [app.DAPIChEditField.Value app.Marker1ChEditField.Value]));
                app.Marker2ChEditField.Value=chchoice2(end);
            end
            app.Marker1ChEditField.Value=marker1value;
            
        end

        % Value changed function: Marker2ChEditField
        function Marker2ChEditFieldValueChanged(app, event)
            if app.sinfo.C>2
                marker2value = app.Marker2ChEditField.Value;
                if marker2value>app.sinfo.C ||...
                        marker2value==app.DAPIChEditField.Value ||...
                        marker2value==app.Marker1ChEditField.Value
                    chchoice=find(~ismember(1:app.sinfo.C,...
                        [app.DAPIChEditField.Value app.Marker1ChEditField.Value]));
                    marker2value=chchoice(end);
                end
                app.Marker2ChEditField.Value=marker2value;
            else
                app.Marker2ChEditField.Value=0;
            end
            
        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            value = app.ChannelSpinner.Value;
            newIm=max(app.Stack(:,:,:,value),[],3);
            app.himLam.CData=newIm;
            if any(app.sinfo.wavelength)
            app.wltext.String=['Ex: ' int2str(app.sinfo.wavelength(value)) ' nm'];
            else
             app.wltext.String='Ex: N.D'; 
            end
            if ~app.PROCEEDButton.Enable
            app.hima.CData=app.CropStack(:,:,app.zsliceSpinner.Value,value);
            app.UIAxes_zSlices.CLim=[0 app.qimax(value)];
            end

        end

        % Button pushed function: PROCEEDButton
        function PROCEEDButtonPushed(app, event)
            
            
            app.DAPIMP=max(app.Stack(:,:,:,app.DAPIChEditField.Value),[],3);
            app.DAPIStack=app.Stack(:,:,:,app.DAPIChEditField.Value);
            app.LaminStack=app.Stack(:,:,:,app.Marker1ChEditField.Value);
            if app.Marker2ChEditField.Value
            app.Lamin2Stack=app.Stack(:,:,:,app.Marker2ChEditField.Value);
            else
                app.Lamin2Stack=zeros(size(app.LaminStack),app.sinfo.PixelType);
            end
            app.himLam.CData=app.DAPIMP;
            app.DAPIChEditField.Editable="off";
            app.DAPIChEditField.Enable="off";
            app.Marker1ChEditField.Editable="off";
            app.Marker1ChEditField.Enable="off";
            app.Marker2ChEditField.Editable="off";
            app.Marker2ChEditField.Enable="off";
            app.zsliceSpinner.Enable='on';
            %app.ChannelSpinner.Editable="off";
            %app.ChannelSpinner.Enable="off";
            app.himLam.PickableParts='none';
            app.himLam.HitTest='off';
            app.k=1;
            
            Lp=max(app.LaminStack,[],3);
            app.Thresh = max([min(max(Lp,[],1)) min(max(Lp,[],2))])	 ;
            DAPIBW=imbinarize(app.DAPIMP);
            fp=fspecial('average',10);
            LMWf=imfilter(DAPIBW,fp);
            LMWf=imclearborder(bwareaopen(LMWf,500));
            app.SLamin=regionprops(LMWf,'PixelIdxList','BoundingBox');
            if ~isempty(app.SLamin)
                BBox=app.SLamin(1).BoundingBox;
                BBox=BBox+[-10 -10 20 20];
                rectangle(app.UIAxes_Nuclei,'Position',BBox,'EdgeColor','r','LineWidth',2);
            end

            cub=[BBox(1) BBox(2) 1 BBox(3) BBox(4) app.sinfo.Z-1];
            
            app.DAPICrop=imcrop3(app.DAPIStack,cub);
            app.Lamin2Crop=imcrop3(app.Lamin2Stack,cub);
            app.LaminStackCrop=imcrop3(app.LaminStack,cub);
            [cx, cy, cz]=size(app.DAPICrop);
            if app.sinfo.C==2
            app.CropStack=zeros(cx, cy, cz, app.sinfo.C+1, app.sinfo.PixelType);
            app.CropStack(:,:,:,3)=app.Lamin2Crop;
            else
            app.CropStack=zeros(cx, cy, cz, app.sinfo.C, app.sinfo.PixelType);
            app.CropStack(:,:,:,app.Marker2ChEditField.Value)=app.Lamin2Crop; 
            end
            app.CropStack(:,:,:,app.DAPIChEditField.Value)=app.DAPICrop;
            app.CropStack(:,:,:,app.Marker1ChEditField.Value)=app.LaminStackCrop;
            %app.CropStack(:,:,:,app.Marker2ChEditField.Value)=app.Lamin2Crop;
            
            app.qimax=zeros(app.sinfo.C,1);
            for i=1:app.sinfo.C
                is=imcrop3(app.Stack(:,:,:,i),cub);
                app.qimax(i)=quantile(is(:),0.99);
            end
%             app.qimax(app.DAPIChEditField.Value)=quantile(app.DAPICrop(:),0.99);
%             app.qimax(app.Marker1ChEditField.Value)=quantile(app.LaminStackCrop(:),0.99);
%             app.qimax(app.Marker2ChEditField.Value)=quantile(app.Lamin2Crop(:),0.99);
            app.hima=imshow(app.CropStack(:,:,app.zsliceSpinner.Value,...
                app.ChannelSpinner.Value),...
                [0 app.qimax(app.ChannelSpinner.Value)],'Parent',app.UIAxes_zSlices);

            app.origLamin=zeros(BBox(4)+10,BBox(3)+10,app.sinfo.Z,'like',app.DAPIMP);
            app.origLamin(5:end-5,5:end-5,:)=app.LaminStackCrop;
            %ThreshFactor=app.ThresholdSlider.Value;
            %app.MESHW=LThreshold2(app.origLamin,app.Thresh,ThreshFactor);
            app.MESHW=LThreshold3(app.origLamin);

            app.origDAPI=zeros(BBox(4)+10,BBox(3)+10,app.sinfo.Z,'like',app.DAPIMP);
            app.origDAPI(5:end-5,5:end-5,:)=app.DAPICrop;
            
            app.MESHDW=LThreshold(app.origDAPI,1);

            app.origLamin2=zeros(BBox(4)+10,BBox(3)+10,app.sinfo.Z,'like',app.DAPIMP);
            app.origLamin2(5:end-5,5:end-5,:)=app.Lamin2Crop;
            
            app.lvolmesh=volshow(app.MESHW,...
                'Parent',app.LaminMeshPanel);
            app.lvolmesh.Transformation.A(3,3)=...
                app.sinfo.PhysicalZ/app.sinfo.PhysicalXY;
            app.PROCEEDButton.Enable="off";


        end

        % Button down function: UIAxes_Nuclei
        function UIAxes_NucleiButtonDown(app, event)
            delete(findobj(app.UIAxes_Nuclei,'Type','Rectangle'));
            app.UITable.Data=[];
            app.HOLESW(app.HOLESW)=false;
            app.fHOLESW(app.fHOLESW)=false;
            app.SAVEButton_NUC.Enable='off';
            
            app.zsliceSpinner.Value=1;

            if ~isempty(app.LaminMeshPanel.Children)
                delete(app.LaminMeshPanel.Children);
            end
            if ~isempty(app.LaminHolesPanel.Children)
                delete(app.LaminHolesPanel.Children)
            end
            if ~isempty(app.OverlayPanel.Children)
                delete(app.OverlayPanel.Children)
            end


            xyloc=app.UIAxes_Nuclei.CurrentPoint;
            xylocID=sub2ind(size(app.DAPIMP),round(xyloc(4)),round(xyloc(1)));
            tf=cellfun(@(x) ismember(xylocID,x),{app.SLamin.PixelIdxList});
            app.k=find(tf);
            if app.k
                BBox=app.SLamin(app.k).BoundingBox;
                BBox=BBox+[-10 -10 20 20];
                rectangle(app.UIAxes_Nuclei,'Position',BBox,'EdgeColor','r','LineWidth',2);

                cub=[BBox(1) BBox(2) 1 BBox(3) BBox(4) app.sinfo.Z-1];
                app.DAPICrop=imcrop3(app.DAPIStack,cub);
                app.LaminStackCrop=imcrop3(app.LaminStack,cub);
                app.Lamin2Crop=imcrop3(app.Lamin2Stack,cub);
                [cx, cy, cz]=size(app.DAPICrop);
             if app.sinfo.C==2
            app.CropStack=zeros(cx, cy, cz, app.sinfo.C+1, app.sinfo.PixelType);
            app.CropStack(:,:,:,3)=app.Lamin2Crop;
            else
            app.CropStack=zeros(cx, cy, cz, app.sinfo.C, app.sinfo.PixelType);
            app.CropStack(:,:,:,app.Marker2ChEditField.Value)=app.Lamin2Crop; 
            end
            app.CropStack(:,:,:,app.DAPIChEditField.Value)=app.DAPICrop;
            app.CropStack(:,:,:,app.Marker1ChEditField.Value)=app.LaminStackCrop;
            %app.CropStack(:,:,:,app.Marker2ChEditField.Value)=app.Lamin2Crop;
            
            app.qimax=zeros(app.sinfo.C,1);
            for i=1:app.sinfo.C
                is=imcrop3(app.Stack(:,:,:,i),cub);
                app.qimax(i)=quantile(is(:),0.99);
            end
%             app.qimax(app.DAPIChEditField.Value)=quantile(app.DAPICrop(:),0.99);
%             app.qimax(app.Marker1ChEditField.Value)=quantile(app.LaminStackCrop(:),0.99);
%             app.qimax(app.Marker2ChEditField.Value)=quantile(app.Lamin2Crop(:),0.99);
                app.hima=imshow(app.CropStack(:,:,app.zsliceSpinner.Value),...
                    [0 app.qimax(app.ChannelSpinner.Value)],'Parent',app.UIAxes_zSlices);


                app.origLamin=zeros(BBox(4)+10,BBox(3)+10,app.sinfo.Z,'like',app.DAPIMP);
                %cub=[BBox(1) BBox(2) 1 BBox(3) BBox(4) app.sinfo.Z-1];
                lcr=app.LaminStackCrop;
                app.origLamin(5:end-5,5:end-5,:)=lcr;
                
                %ThreshFactor=app.ThresholdSlider.Value;
                %app.MESHW=LThreshold2(app.origLamin,app.Thresh,ThreshFactor);
                app.MESHW=LThreshold3(app.origLamin);
                

                app.origDAPI=zeros(BBox(4)+10,BBox(3)+10,app.sinfo.Z,'like',app.DAPIMP);
                %cub=[BBox(1) BBox(2) 1 BBox(3) BBox(4) app.sinfo.Z-1];
                lcr=app.DAPICrop;
                app.origDAPI(5:end-5,5:end-5,:)=lcr;
                
                
                app.MESHDW=LThreshold(app.origDAPI,1);

                app.origLamin2=zeros(BBox(4)+10,BBox(3)+10,app.sinfo.Z,'like',app.DAPIMP);
                %cub=[BBox(1) BBox(2) 1 BBox(3) BBox(4) app.sinfo.Z-1];
                lcr=app.Lamin2Crop;
                app.origLamin2(5:end-5,5:end-5,:)=lcr;
                
                app.lvolmesh=volshow(app.MESHW,...
                    'Parent',app.LaminMeshPanel);
                app.lvolmesh.Transformation.A(3,3)=...
                    app.sinfo.PhysicalZ/app.sinfo.PhysicalXY;
            end
            %app.SAVEButton_NUC.Enable='on';
            app.Switch.Value='3D Vol';
            app.Switch.Enable='off';

        end

        % Button pushed function: ComputeHolesButton
        function ComputeHolesButtonPushed(app, event)
            app.UITable.Data=[];
            if ~isempty(app.sax)
                delete(app.sax);
            end
            app.SizeFilterSpinner.Enable='on';
            if app.Switch.Value=="Curvature"
%                 CamPos=[app.sax.CameraPosition(1:2)/app.sinfo.PhysicalXY,...
%                 app.sax.CameraPosition(3)/app.sinfo.PhysicalZ];
%             CamTarg=[app.sax.CameraTarget(1:2)/app.sinfo.PhysicalXY,...
%                 app.sax.CameraTarget(3)/app.sinfo.PhysicalZ];
%             %CamZoom=app.sax.CameraZoom;
%             CamUpV=[app.sax.CameraUpVector(1:2)/app.sinfo.PhysicalXY,...
%                 app.sax.CameraUpVector(3)/app.sinfo.PhysicalZ]; 
%             app.sax.Visible='off';
%             
%             viewertoggle=app.lvolmesh.Parent;
%             viewertoggle.CameraPosition=CamPos;
%             viewertoggle.CameraTarget=CamTarg;
%             %viewertoggle.CameraZoom=CamZoom;
%             viewertoggle.CameraUpVector=CamUpV;
            viewertoggle.Visible='on';
            end
            
            app.Switch.Value="3D Vol";
            oldpointer=app.UIFigure.Pointer;
            app.UIFigure.Pointer="watch";drawnow;
            
            [app.HOLESW, app.PerimW,app.LaminHULLW]=computeLaminHolesv2(...
                app.MESHDW,app.MESHW,1, app.CheckBox_OutsideIn.Value, app.CheckBox_InsideOut.Value);

            if app.CheckBox_IgnoreHoles.Value
                app.HOLESW=removeFloorHoles(app.HOLESW,app.LaminHULLW,app.sinfo.PhysicalZ);
            end

            app.fHOLESW=false(size(app.HOLESW));
            SHoles=regionprops3(app.HOLESW,'Volume', 'Centroid');
            if ~isempty(SHoles)
                vholes=SHoles.Volume*app.sinfo.PhysicalXY^2*app.sinfo.PhysicalZ;
                app.MinLabel.Text=["Min. Vol.: " num2str(min(vholes),2)];
                app.MaxLabel.Text=["Max. Vol.: " num2str(max(vholes),2)];
            end
            app.UIFigure.Pointer=oldpointer;drawnow;
            app.SizeFilterSpinner.Value=0;
            app.lvolholes=volshow(app.HOLESW,...
                'Parent',app.LaminHolesPanel);
            app.lvolholes.Transformation.A(3,3)=...
                app.sinfo.PhysicalZ/app.sinfo.PhysicalXY;
            app.holeviewer=app.lvolholes.Parent;


            app.lvolcombo=volshow(app.MESHW,'Parent',app.OverlayPanel,...
                'Colormap',[1 0 0]);
            app.lvolcombo.Transformation.A(3,3)=...
                app.sinfo.PhysicalZ/app.sinfo.PhysicalXY;
            app.viewer=app.lvolcombo.Parent;
            app.lvolcombholes=volshow(app.HOLESW,'Parent',app.viewer,...
                'Colormap',[0 0 1]);
            app.lvolcombholes.Transformation.A(3,3)=...
                app.sinfo.PhysicalZ/app.sinfo.PhysicalXY;
            app.viewer.BackgroundColor=[0.5 0.5 0.5];

            app.lvolholeonlyselect=volshow(false(size(app.HOLESW)),'Parent',app.holeviewer);
            app.lvolholeonlyselect.Data=[];
            app.lvolholeonlyselect.Colormap=[0 1 0];
            app.lvolholeonlyselect.Transformation.A(3,3)=...
                app.sinfo.PhysicalZ/app.sinfo.PhysicalXY;

            app.lvolholeselect=volshow(false(size(app.HOLESW)),'Parent',app.viewer);
            app.lvolholeselect.Data=[];
            app.lvolholeselect.Colormap=[0 1 0];
            app.lvolholeselect.Transformation.A(3,3)=...
                app.sinfo.PhysicalZ/app.sinfo.PhysicalXY;

            app.SAVEButton_NUC.Enable='on';

            %%%%%%%% Compute Curvature %%%%%%%%%%%%%%%%%%%

            PWP=app.PerimW;
            kk=find(PWP);
            [x,y,z]=ind2sub(size(PWP),kk);
            S=regionprops3(PWP,'SurfaceArea');
            sa=S.SurfaceArea*app.sinfo.PhysicalXY^2;
            pden=round(sa*10);
            if pden<numel(kk)
            r=randperm(numel(kk),pden);
            else
                r=1:numel(kk);
            end
            P=[x y z];
            P(:,1:2)=P(:,1:2)*app.sinfo.PhysicalXY;
            P(:,3)=P(:,3)*app.sinfo.PhysicalZ;
            Pr=P(r,:);
            shp=alphaShape(Pr,inf);
            [app.brf, app.Prb]=shp.boundaryFacets;
            [ ~, app.curvature ] = findPointNormals(app.Prb);
            app.Switch.Enable='on';
            app.sax=uiaxes('Parent',app.LaminMeshPanel);
            app.hs=trisurf(app.brf,app.Prb(:,1),app.Prb(:,2),app.Prb(:,3),...
                app.curvature,'FaceColor','interp','edgeColor','none','Parent',app.sax);
            app.sax.YDir='Reverse';
            app.sax.Interactions=rotateInteraction;
            clim(app.sax,[0 0.15]);
            colormap(app.sax,"hot");


        end

        % Value changed function: zsliceSpinner
        function zsliceSpinnerValueChanged(app, event)
            value = app.zsliceSpinner.Value;
            app.hima.CData=app.CropStack(:,:,value,app.ChannelSpinner.Value);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;


            switch value
                case '3D Vol'
            CamPos=[app.sax.CameraPosition(1:2)/app.sinfo.PhysicalXY,...
                app.sax.CameraPosition(3)/app.sinfo.PhysicalZ];
            CamTarg=[app.sax.CameraTarget(1:2)/app.sinfo.PhysicalXY,...
                app.sax.CameraTarget(3)/app.sinfo.PhysicalZ];
            %CamZoom=app.sax.CameraZoom;
            CamUpV=[app.sax.CameraUpVector(1:2)/app.sinfo.PhysicalXY,...
                app.sax.CameraUpVector(3)/app.sinfo.PhysicalZ]; 
            app.sax.Visible='off';
            
            viewertoggle=app.lvolmesh.Parent;
            viewertoggle.CameraPosition=CamPos;
            viewertoggle.CameraTarget=CamTarg;
            %viewertoggle.CameraZoom=CamZoom;
            viewertoggle.CameraUpVector=CamUpV;
            viewertoggle.Visible='on';
                case 'Curvature'
            viewertoggle=app.lvolmesh.Parent;
            CamPos=viewertoggle.CameraPosition;
            CamTarg=viewertoggle.CameraTarget;
            %app.sax.CameraViewAngle=camva(viewertoggle);
            CamUpV=viewertoggle.CameraUpVector;
            app.sax.CameraPosition=[CamPos(1:2)*app.sinfo.PhysicalXY, ...
                CamPos(3)*app.sinfo.PhysicalZ];
            app.sax.CameraTarget=[CamTarg(1:2)*app.sinfo.PhysicalXY, ...
                CamTarg(3)*app.sinfo.PhysicalZ];
            %app.sax.CameraZoom=CamZoom;
            app.sax.CameraUpVector=[CamUpV(1:2)*app.sinfo.PhysicalXY, ...
                CamUpV(3)*app.sinfo.PhysicalZ];
            viewertoggle.Visible='off';
            app.sax.Visible='on';
            app.sax.YDir='reverse';
            app.sax.DataAspectRatio=[1 1 1];
            app.sax.DataAspectRatioMode='Manual';
            %app.sax.CameraViewAngleMode='Manual';
            app.sax.XAxis.Visible='off';
            app.sax.YAxis.Visible='off';
            app.sax.ZAxis.Visible='off';
            app.sax.Color='None';

            end


        end

        % Value changed function: CheckBox_InsideOut
        function CheckBox_InsideOutValueChanged(app, event)
            value = app.CheckBox_InsideOut.Value;
            if ~value
                app.CheckBox_OutsideIn.Value=true;
            end
        end

        % Value changed function: CheckBox_OutsideIn
        function CheckBox_OutsideInValueChanged(app, event)
            value = app.CheckBox_OutsideIn.Value;
            if ~value
                app.CheckBox_InsideOut.Value=true;
            end
        end

        % Value changed function: SizeFilterSpinner
        function SizeFilterSpinnerValueChanged(app, event)
            value = app.SizeFilterSpinner.Value;
            filtval=round(value/(app.sinfo.PhysicalXY^2*app.sinfo.PhysicalZ));
            app.fHOLESW=bwareaopen(app.HOLESW,filtval);
            app.lvolholes.Data=app.fHOLESW;
            app.lvolcombholes.Data=app.fHOLESW;
            SHOLES=regionprops3(app.fHOLESW,'Volume');
            if ~isempty(SHOLES)
                app.MinLabel.Text=["Min. Vol.: " num2str(min(SHOLES.Volume)...
                    *app.sinfo.PhysicalXY^2*app.sinfo.PhysicalZ,2)];
                app.MaxLabel.Text=["Max. Vol.: " num2str(max(SHOLES.Volume)...
                    *app.sinfo.PhysicalXY^2*app.sinfo.PhysicalZ,2)];
            else
                app.MinLabel.Text="Min. Vol.:";
                app.MaxLabel.Text="Max. Vol.:";
            end
            app.UITable.Visible='off';
            app.SAVEButton_NUC.Enable='on';
            app.lvolholeselect.Data=false(size(app.HOLESW));
            app.lvolholeonlyselect.Data=false(size(app.HOLESW));
            
        end

        % Button pushed function: SAVEButton_NUC
        function SAVEButton_NUCPushed(app, event)
            if ~any(app.fHOLESW,'all')
                if any(app.HOLESW,'all')
                    app.fHOLESW=app.HOLESW;
                end
            end
            if any(app.fHOLESW,'all')
                app.Ssave=regionprops3(app.fHOLESW,'Volume','Centroid','VoxelIdxList');
                S=app.Ssave;
                S.VoxelIdxList=[];
                S.Centroid=S.Centroid(:,[2 1 3]).*[app.sinfo.PhysicalXY ...
                    app.sinfo.PhysicalXY app.sinfo.PhysicalZ];


            %%% measure curvature close to each hole (10 closest points from
            %%% hull %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            
                cent=S.Centroid;
%                 cent(:,1:2)=cent(:,1:2)*app.sinfo.PhysicalXY;
%                 cent(:,3)=cent(:,3)*app.sinfo.PhysicalZ;
                [idxcurv, ~]=knnsearch(app.Prb,cent,"K",10);
                
                centcurv=app.curvature(idxcurv);
                if size(idxcurv,1)>1
                mcentcurv=mean(centcurv,2);
                elseif size(idxcurv,1)==1
                    mcentcurv=mean(centcurv);
                end
                tmcc=array2table(mcentcurv,'VariableNames',{'meanCurvature'});
                S=[S tmcc];
                SS=table2struct(S,'ToScalar',true);
                SS.Volume=S.Volume*app.sinfo.PhysicalXY^2*app.sinfo.PhysicalZ;
                SS.NucID=app.k;
                SS.SizeFilter=app.SizeFilterSpinner.Value;
                app.exportdata.Holes(app.sncount)=SS;
                app.sncount=app.sncount+1;
            else
                S=[];
            end

            SN=regionprops3(imfill(app.PerimW,'holes'),'Volume','SurfaceArea','PrincipalAxisLength');
            SN=table2struct(SN,'ToScalar',true);
            SN.VolRatio=SN.Volume/numel(find(app.LaminHULLW));
            SN.Volume=SN.Volume*app.sinfo.PhysicalXY^2*app.sinfo.PhysicalZ;
            SN.SurfaceArea=SN.SurfaceArea*app.sinfo.PhysicalXY^2;
            SN.PrincipalAxisLength(:,1:2)=SN.PrincipalAxisLength(:,1:2)*...
                app.sinfo.PhysicalXY;
            SN.PrincipalAxisLength(:,3)=SN.PrincipalAxisLength(:,3)*...
                app.sinfo.PhysicalZ;


            SN.NucID=app.k;
            SI=regionprops3(app.MESHW,app.origLamin,'MeanIntensity');
            SI2=regionprops3(app.MESHW,app.origLamin2,'MeanIntensity');
            SN.LaminMI=SI.MeanIntensity;
            SN.Lamin2MI=SI2.MeanIntensity;
            app.exportdata.Nucleus(app.snncount)=SN;
            app.snncount=app.snncount+1;
            app.SAVEButton_NUC.Enable='off';
            app.EXPORTButton.Enable='on';
            
            if ~isempty(S)
            S.Volume=S.Volume*app.sinfo.PhysicalXY^2*app.sinfo.PhysicalZ;
            v=S.Volume;
            S.Volume=arrayfun(@(x) sprintf('%.2f\n',x),v,'un',0);
            c=S.Centroid;
            S.Centroid=arrayfun(@(x) sprintf('%.1f\n',x),c,'un',0);
            m=S.meanCurvature;
            S.meanCurvature=arrayfun(@(x) sprintf('%.3f\n',x),m,'un',0);
            end
            app.UITable.Visible="on";
            app.UITable.Data=S;


        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            app.lvolcombholes.Data=app.fHOLESW;
            app.lvolholes.Data=app.fHOLESW;
            selection = app.UITable.Selection;
            Vox=app.Ssave.VoxelIdxList{selection};
            app.lvolcombholes.Data(Vox)=false;
            app.lvolholeselect.Data=false(size(app.HOLESW));
            app.lvolholeselect.Data(Vox)=true;

            app.lvolholes.Data(Vox)=false;
            app.lvolholeonlyselect.Data=false(size(app.HOLESW));
            app.lvolholeonlyselect.Data(Vox)=true;


        end

        % Value changed function: SingleFrameCheckBox
        function SingleFrameCheckBoxValueChanged(app, event)
            value = app.SingleFrameCheckBox.Value;
            if value
                app.GIFCheckBox.Value=false;
            end
        end

        % Value changed function: GIFCheckBox
        function GIFCheckBoxValueChanged(app, event)
            value = app.GIFCheckBox.Value;
            if value
                app.SingleFrameCheckBox.Value=false;
            end
        end

        % Button pushed function: SNAPButton
        function SNAPButtonPushed(app, event)
            %exportgraphics(app.OverlayPanel,'test.gif')
            
            app.UIFigure.Visible='off';


            if app.SingleFrameCheckBox.Value
                filter = {'*.jpg';'*.png';'*.tif';'*.pdf';'*.gif'};
                t=datetime('now','TimeZone','local','Format','y-MMdd-HHmm');
                [sname,filepath] = uiputfile(filter,'Saving 3D snaphsots...',['Nuc_' int2str(app.k)]);
                if app.CheckBox_3.Value
                    F=getframe(app.UIFigure,app.OverlayPanel.InnerPosition);
                    sname=[ '_Overlay_' char(t) '_' sname];
                    if ischar(sname)
                        imwrite(F.cdata,[filepath sname]);
                    end
                end

                if app.CheckBox_2.Value
                    F=getframe(app.UIFigure,app.LaminHolesPanel.InnerPosition);
                    sname=['_Holes_' char(t) '_' sname ];
                    if ischar(sname)
                        imwrite(F.cdata,[filepath sname]);
                    end
                end

                if app.CheckBox_1.Value
                    F=getframe(app.UIFigure,app.LaminMeshPanel.InnerPosition);
                    sname=['_LaminMesh_' char(t) '_' sname];
                    if ischar(sname)
                        imwrite(F.cdata,[filepath sname]);
                    end
                end
            elseif app.GIFCheckBox.Value

                filter = {'*.gif'};
                t=datetime('now','TimeZone','local','Format','y-MMdd-HHmm');
                [sname,filepath] = uiputfile(filter,'Saving 3D snaphsots...',['Nuc_' int2str(app.k)]);
                commandwindow;
                
                if app.CheckBox_1.Value
                    progressText(0,'Saving Lamin GIF animation...')
                    sname=['_LaminMesh_' char(t) '_' sname];
                    sz = size(app.lvolmesh.Data);
                    center = sz/2 + 0.5;
                    viewersnap=app.lvolmesh.Parent;
                    %viewersnap.CameraTarget = center;
                    %center=viewsnap.CameraTarget;
                    zpos=viewersnap.CameraPosition(3);
                    vec = linspace(0,2*pi,60)';
                    dist = sqrt(sz(1)^2 + sz(2)^2 + sz(3)^2);
                    myPosition = center + [cos(vec)*dist sin(vec)*dist ones(size(vec))*zpos];

                    for idx = 1:length(vec)
                        % Update the current view
                        viewersnap.CameraPosition = myPosition(idx,:);
                        % Capture the image using the getframe function
                        I = getframe(app.UIFigure,app.LaminMeshPanel.InnerPosition);
                        [indI,cm] = rgb2ind(I.cdata,256);
                        % Write the frame to the GIF file
                        if idx==1
                            % Do nothing. The first frame displays only the viewer, not the
                            % volume.
                        elseif idx == 2
                            imwrite(indI,cm,[filepath sname],...
                                "gif",Loopcount=inf,DelayTime=0)
                        else
                            imwrite(indI,cm,[filepath sname],...
                                "gif",WriteMode="append",DelayTime=0)
                        end
                        progressText(idx/length(vec));
                    end
                end
                if app.CheckBox_2.Value
                    progressText(0,'Saving Holes GIF animation...')
                    sname=[ '_Holes_' char(t) '_' sname];
                    sz = size(app.lvolmesh.Data);
                    center = sz/2 + 0.5;
                    viewersnap=app.lvolholes.Parent;
                    zpos=viewersnap.CameraPosition(3);
                    %viewersnap.CameraTarget = center;
                    
                    
                    vec = linspace(0,2*pi,60)';
                    dist = sqrt(sz(1)^2 + sz(2)^2 + sz(3)^2);
                    myPosition = center + [cos(vec)*dist sin(vec)*dist ones(size(vec))*zpos];

                    for idx = 1:length(vec)
                        % Update the current view
                        viewersnap.CameraPosition = myPosition(idx,:);
                        % Capture the image using the getframe function
                        I = getframe(app.UIFigure,app.LaminHolesPanel.InnerPosition);
                        [indI,cm] = rgb2ind(I.cdata,256);
                        % Write the frame to the GIF file
                        if idx==1
                            % Do nothing. The first frame displays only the viewer, not the
                            % volume.
                        elseif idx == 2
                            imwrite(indI,cm,[filepath sname],...
                                "gif",Loopcount=inf,DelayTime=0)
                        else
                            imwrite(indI,cm,[filepath sname],...
                                "gif",WriteMode="append",DelayTime=0)
                        end
                        progressText(idx/length(vec));
                    end
                end
                if app.CheckBox_3.Value
                    progressText(0,'Saving Overlay GIF animation...')
                    sname=['_Overlay_' char(t) '_' sname];
                    sz = size(app.lvolmesh.Data);
                    center = sz/2 + 0.5;
                    viewersnap=app.viewer;
                    %viewersnap.CameraTarget = center;
                    %center=viewersnap.CameraTarget;
                    zpos=viewersnap.CameraPosition(3);
                    vec = linspace(0,2*pi,60)';
                    dist = sqrt(sz(1)^2 + sz(2)^2 + sz(3)^2);
                    myPosition = center + [cos(vec)*dist sin(vec)*dist ones(size(vec))*zpos];

                    for idx = 1:length(vec)
                        % Update the current view
                        viewersnap.CameraPosition = myPosition(idx,:);
                        % Capture the image using the getframe function
                        I = getframe(app.UIFigure,app.OverlayPanel.InnerPosition);
                        [indI,cm] = rgb2ind(I.cdata,256);
                        % Write the frame to the GIF file
                        if idx==1
                            % Do nothing. The first frame displays only the viewer, not the
                            % volume.
                        elseif idx == 2
                            imwrite(indI,cm,[filepath sname],...
                                "gif",Loopcount=inf,DelayTime=0)
                        else
                            imwrite(indI,cm,[filepath sname],...
                                "gif",WriteMode="append",DelayTime=0)
                        end
                        progressText(idx/length(vec));
                    end
                end
            end
            app.UIFigure.Visible='on';
        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            fields={'Holes','Nucleus'};
            t=datetime('now','TimeZone','local','Format','yMMddHHmm');
            sxname=[app.filename(1:end-4) '_' char(t) '.xlsx'];
            if ~any(isfield(app.exportdata,fields))
                uialert(app.UIFigure,'No Data to export','WARNING');
                return
            end
            app.UIFigure.Visible='off';
            [p, f, e]=fileparts(sxname);
            [file, path]=uiputfile('*.xlsx','Export As...',f);
            xfname=fullfile(path,file);

            if isfield(app.exportdata,'Holes')
            nn=cellfun(@numel,{app.exportdata.Holes.Volume});
            rnnuc=repelem([app.exportdata.Holes.NucID],nn);
            v=vertcat(app.exportdata.Holes.Volume);
            c=vertcat(app.exportdata.Holes.Centroid);
            cc=vertcat(app.exportdata.Holes.meanCurvature);
            rsf=repelem([app.exportdata.Holes.SizeFilter],nn);
            ArHoles=[rnnuc' v c cc rsf'];


            rnnnuc=[app.exportdata.Nucleus.NucID];
            nv=[app.exportdata.Nucleus.Volume];
            ns=[app.exportdata.Nucleus.SurfaceArea];
            npa=vertcat(app.exportdata.Nucleus.PrincipalAxisLength);
            mi=vertcat(app.exportdata.Nucleus.LaminMI);
            mi2=vertcat(app.exportdata.Nucleus.Lamin2MI);
            ArNuc=[rnnnuc' nv' ns' npa mi mi2];


            THoles=array2table(ArHoles,'VariableNames',...
                {'nucID','Volume','Centroid-x','Centroid-y','Centroid-z',...
                'meanCurvature','SizeFilterValue'});
            writetable(THoles,xfname,'sheet','Holes');

            TNuc=array2table(ArNuc,'VariableNames',...
                {'nucID','Volume','SurfaceArea',...
                'PrincipalAxis-a','PrincipalAxis-b','PrincipalAxis-c',...
                'LaminMeanIntensity','Marker2MeanIntensity'});
            writetable(TNuc,xfname,'sheet','Nucleus');
            app.EXPORTButton.Enable='off';
            end
            app.UIFigure.Visible='on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Nuclei = uiaxes(app.UIFigure);
            title(app.UIAxes_Nuclei, 'Nucleus Selection')
            app.UIAxes_Nuclei.XTick = [];
            app.UIAxes_Nuclei.YTick = [];
            app.UIAxes_Nuclei.ButtonDownFcn = createCallbackFcn(app, @UIAxes_NucleiButtonDown, true);
            app.UIAxes_Nuclei.Visible = 'off';
            app.UIAxes_zSlices = uiaxes(app.UIFigure);
            title(app.UIAxes_zSlices, 'Slice selection')
            app.UIAxes_zSlices.XTick = [];
            app.UIAxes_zSlices.YTick = [];
            app.UIAxes_zSlices.Visible = 'off';
            app.LaminMeshPanel = uipanel(app.UIFigure);
            app.LaminMeshPanel.Title = 'Lamin Mesh';
            app.LaminHolesPanel = uipanel(app.UIFigure);
            app.LaminHolesPanel.Title = 'Lamin Holes';
            app.OverlayPanel = uipanel(app.UIFigure);
            app.OverlayPanel.Title = 'Overlay';
            app.Marker1ChEditFieldLabel = uilabel(app.UIFigure);
            app.Marker1ChEditFieldLabel.Text = 'Marker1 Ch';
            app.Marker2ChEditFieldLabel = uilabel(app.UIFigure);
            app.Marker2ChEditFieldLabel.Text = 'Marker2 Ch';
            app.ChannelSpinnerLabel = uilabel(app.UIFigure);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.zsliceSpinnerLabel = uilabel(app.UIFigure);
            app.zsliceSpinnerLabel.Text = 'z-slice';
            app.SizeFilterSpinnerLabel = uilabel(app.UIFigure);
            app.SizeFilterSpinnerLabel.Text = 'Size Filter';
            app.EXPORTButton = uibutton(app.UIFigure, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.SNAPButton = uibutton(app.UIFigure, 'push');
            app.SNAPButton.ButtonPushedFcn = createCallbackFcn(app, @SNAPButtonPushed, true);
            app.SNAPButton.Text = 'SNAP';
            app.SingleFrameCheckBox = uicheckbox(app.UIFigure);
            app.SingleFrameCheckBox.ValueChangedFcn = createCallbackFcn(app, @SingleFrameCheckBoxValueChanged, true);
            app.SingleFrameCheckBox.Text = 'Single Frame';
            app.SingleFrameCheckBox.Value = true;
            app.GIFCheckBox = uicheckbox(app.UIFigure);
            app.GIFCheckBox.ValueChangedFcn = createCallbackFcn(app, @GIFCheckBoxValueChanged, true);
            app.GIFCheckBox.Text = '360 GIF';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.SAVEButton_NUC = uibutton(app.UIFigure, 'push');
            app.SAVEButton_NUC.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_NUCPushed, true);
            app.SAVEButton_NUC.Text = 'SAVE';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Volume'; 'Centroid'; 'Curvature'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.UITable.Multiselect = 'off';
            app.UITable.Visible = 'off';
            app.SizeFilterSpinner = uispinner(app.UIFigure);
            app.SizeFilterSpinner.Step = 0.1;
            app.SizeFilterSpinner.Limits = [0 Inf];
            app.SizeFilterSpinner.ValueChangedFcn = createCallbackFcn(app, @SizeFilterSpinnerValueChanged, true);
            app.SizeFilterSpinner.Enable = 'off';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = '';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'3D Vol', 'Curvature'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = '3D Vol';
            app.ComputeHolesButton = uibutton(app.UIFigure, 'push');
            app.ComputeHolesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeHolesButtonPushed, true);
            app.ComputeHolesButton.Text = 'Compute Holes';
            app.DAPIChEditFieldLabel = uilabel(app.UIFigure);
            app.DAPIChEditFieldLabel.Text = 'DAPI Ch';
            app.CheckBox_OutsideIn = uicheckbox(app.UIFigure);
            app.CheckBox_OutsideIn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_OutsideInValueChanged, true);
            app.CheckBox_OutsideIn.Value = true;
            app.OutsideInRaysLabel = uilabel(app.UIFigure);
            app.OutsideInRaysLabel.Text = 'Outside In Rays';
            app.CheckBox_InsideOut = uicheckbox(app.UIFigure);
            app.CheckBox_InsideOut.ValueChangedFcn = createCallbackFcn(app, @CheckBox_InsideOutValueChanged, true);
            app.CheckBox_InsideOut.Value = true;
            app.InsideOutRaysLabel = uilabel(app.UIFigure);
            app.InsideOutRaysLabel.Text = 'Inside Out Rays';
            app.CheckBox_IgnoreHoles = uicheckbox(app.UIFigure);
            app.IgnorefloorholesLabel = uilabel(app.UIFigure);
            app.IgnorefloorholesLabel.Text = 'Ignore floor holes';
            app.zsliceSpinner = uispinner(app.UIFigure);
            app.zsliceSpinner.Limits = [1 Inf];
            app.zsliceSpinner.ValueChangedFcn = createCallbackFcn(app, @zsliceSpinnerValueChanged, true);
            app.zsliceSpinner.Enable = 'off';
            app.zsliceSpinner.Value = 1;
            app.ChannelSpinner = uispinner(app.UIFigure);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Enable = 'off';
            app.ChannelSpinner.Value = 1;
            app.PROCEEDButton = uibutton(app.UIFigure, 'push');
            app.PROCEEDButton.ButtonPushedFcn = createCallbackFcn(app, @PROCEEDButtonPushed, true);
            app.PROCEEDButton.Enable = 'off';
            app.PROCEEDButton.Text = 'PROCEED';
            app.Marker2ChEditField = uieditfield(app.UIFigure, 'numeric');
            app.Marker2ChEditField.ValueChangedFcn = createCallbackFcn(app, @Marker2ChEditFieldValueChanged, true);
            app.Marker2ChEditField.Enable = 'off';
            app.Marker2ChEditField.Value = 3;
            app.Marker1ChEditField = uieditfield(app.UIFigure, 'numeric');
            app.Marker1ChEditField.ValueChangedFcn = createCallbackFcn(app, @Marker1ChEditFieldValueChanged, true);
            app.Marker1ChEditField.Enable = 'off';
            app.Marker1ChEditField.Value = 2;
            app.DAPIChEditField = uieditfield(app.UIFigure, 'numeric');
            app.DAPIChEditField.ValueChangedFcn = createCallbackFcn(app, @DAPIChEditFieldValueChanged, true);
            app.DAPIChEditField.Enable = 'off';
            app.DAPIChEditField.Value = 1;
            app.IMPORTButton = uibutton(app.UIFigure, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaminAnalyzerClean

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end